classdef ExportOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SystemDescriptionCheckBox     matlab.ui.control.CheckBox
        OKButton                      matlab.ui.control.Button
        BatteryPerformanceCheckBox    matlab.ui.control.CheckBox
        MaximumSpeedsCheckBox         matlab.ui.control.CheckBox
        ShipResistancePlotCheckBox    matlab.ui.control.CheckBox
        ShipResistanceTableCheckBox   matlab.ui.control.CheckBox
        SimulationPlotsCheckBox       matlab.ui.control.CheckBox
        OperationalDataTableCheckBox  matlab.ui.control.CheckBox
        ArchitectureDiagramCheckBox   matlab.ui.control.CheckBox
    end

    properties (Access = private) 
        CallingApp   % Main app object
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, isArchitectureDiagram, isOperationalDataTable, isSimulationPlots, isShipResistanceTable, isShipResistancePlot, isMaximumSpeeds, isBatteryPerformance, isSystemDescription)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.ArchitectureDiagramCheckBox.Value = isArchitectureDiagram;
            app.OperationalDataTableCheckBox.Value = isOperationalDataTable;
            app.SimulationPlotsCheckBox.Value = isSimulationPlots;
            app.ShipResistancePlotCheckBox.Value = isShipResistancePlot;
            app.ShipResistanceTableCheckBox.Value = isShipResistanceTable;
            app.MaximumSpeedsCheckBox.Value = isMaximumSpeeds;
            app.BatteryPerformanceCheckBox.Value = isBatteryPerformance;
            app.SystemDescriptionCheckBox.Value = isSystemDescription;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)


            % Call main app's public function
            updateExportSelection(app.CallingApp, 1, app.ArchitectureDiagramCheckBox.Value, app.OperationalDataTableCheckBox.Value, app.SimulationPlotsCheckBox.Value, app.ShipResistancePlotCheckBox.Value, app.ShipResistanceTableCheckBox.Value, app.MaximumSpeedsCheckBox.Value, app.BatteryPerformanceCheckBox.Value, app.SystemDescriptionCheckBox.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ArchitectureDiagramCheckBox = uicheckbox(app.UIFigure);
            app.ArchitectureDiagramCheckBox.Text = 'Architecture Diagram';
            app.OperationalDataTableCheckBox = uicheckbox(app.UIFigure);
            app.OperationalDataTableCheckBox.Text = 'Operational Data Table';
            app.SimulationPlotsCheckBox = uicheckbox(app.UIFigure);
            app.SimulationPlotsCheckBox.Text = 'Simulation Plots';
            app.ShipResistanceTableCheckBox = uicheckbox(app.UIFigure);
            app.ShipResistanceTableCheckBox.Text = 'Ship Resistance Table';
            app.ShipResistancePlotCheckBox = uicheckbox(app.UIFigure);
            app.ShipResistancePlotCheckBox.Text = 'Ship Resistance Plot';
            app.MaximumSpeedsCheckBox = uicheckbox(app.UIFigure);
            app.MaximumSpeedsCheckBox.Text = 'Maximum Speeds';
            app.BatteryPerformanceCheckBox = uicheckbox(app.UIFigure);
            app.BatteryPerformanceCheckBox.Text = 'Battery Performance';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SystemDescriptionCheckBox = uicheckbox(app.UIFigure);
            app.SystemDescriptionCheckBox.Text = 'System Description';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end